classdef SNATCH_IT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Container                     matlab.ui.container.Panel
        SettingsPanel                 matlab.ui.container.Panel
        LengthSpinner                 matlab.ui.control.Spinner
        LengthLabel                   matlab.ui.control.Label
        ModeLabel                     matlab.ui.control.Label
        ModeDD                        matlab.ui.control.DropDown
        SettingsLabel                 matlab.ui.control.Label
        HoardPanel                    matlab.ui.container.Panel
        RemainingTilesEditField       matlab.ui.control.EditField
        RemainingTilesEditFieldLabel  matlab.ui.control.Label
        NewWordDD                     matlab.ui.control.DropDown
        NewTileButton                 matlab.ui.control.Button
        NewWordEditField              matlab.ui.control.EditField
        ValidWordLamp                 matlab.ui.control.Lamp
        HoardLabel                    matlab.ui.control.Label
        NewGameButton                 matlab.ui.control.Button
        PoolPanel                     matlab.ui.container.Panel
        PoolLabel                     matlab.ui.control.Label
    end


    properties (Access = private)
    tileBag     % cell array to hold tiles
    pool        % cell array to hold revealed tiles
    hoard       % cell array to hold 'hoard' of words
    dictionary  % cell array to hold legal/permitted English words

end


methods (Access = private)
 
%=== ADD WORD TO HOARD AFTER SUCESSFUL SUBMISSION
function submitWord(app, word)
    %= Word may be valid/legal (min length, in dictionary) BUT must also be
    %drawn from tiles in pool or hoard

    % Convert word to uppercase
    word = upper(word);


    %== CONFIRM VALIDITY
    % proceed only if word is 1) in dictionary and 2) min length or more
    if isValidWord(app, word)
        % Split word into individual letters
        letters = cellstr(word');

        %=== FOR NEW WORDS:
        if strcmp(app.NewWordDD.Value, 'NEW WORD')
            
            % Check if all letters in the word are in the pool
            for i = 1:length(letters)
                idx = find(strcmp(app.pool, letters{i}), 1);
                if isempty(idx)
                    app.ValidWordLamp.Color = 'red';
                    return;
                end
                app.pool(idx) = [];
            end

            % Add new word to hoard
            app.hoard{end+1} = word;

            % end of functionality for adding new word


        %=== FOR STOLEN WORDS:
        elseif strcmp(app.NewWordDD.Value, 'STEAL WORD')
        
            % Find a word in the hoard that could be transformed
            for i = 1:numel(app.hoard)
                hoardWord = app.hoard{i};
                
                if app.canForm(hoardWord, letters, app.pool)
                    % Replace  hoard word
                    app.hoard{i} = word;
                    break;
                end

            end
        end
    
    %=== UPDATE HOARD DISPLAY (NEW WORD SUBMITTED)
    % [Add code here to update the hoard display, if necessary]
   
    %=== LAMP GREEN (NEW WORD SUBMITTED)
    app.ValidWordLamp.Color = 'green';

    else    % Lamp indicates the word is invalid
        app.ValidWordLamp.Color = 'red';
        return;
    end

    
end            


function canForm = canForm(hoardWord, letters, pool)
        %== HELPER FUNCTION
        hoardLetters = cellStr(hoardWord');
        remainingLetters = letters;

        % Do all a hoard word's letters fit in proposed word?
        for ii = 1:length(hoardLetters)
        idx = find(strcmp(remainingLetters, hoardLetters{ii}), 1);
            if ~isempty(idx)
                remainingLetters(idx) = [];
            else
                canForm = false;
                return;
            end
        end

        % Are remaining letters in pool?
        for jj = 1:length(remainingLetters)
            idx = find(strcmp(pool, remainingLetters{jj}), 1);
            if isempty(idx)
                canForm = false;
                return;
            end
        end

        canForm = true;
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @WindowSizeChanged, true);
            
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @PressEnter, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @PressSpace, true);
        end
        % Code that executes after component creation


        function LoadDictionary(app)

        %== LOAD ENABLE DICTIONARY. 
        % Located in the directory, but any user needs to access/have it found
        % Use this tO determine if user's new word / stolen word is valid
            
        
        % Get directory of the current .mlapp file
        appDir = fileparts(mfilename('fullpath'));
            
        % Define dictionary file path relative to the app directory
        dictionaryFile = fullfile(appDir, 'ENABLE_dictionary.txt');
            
        % Check if the dictionary file exists
        if exist(dictionaryFile, 'file') ~= 2
            uialert(app.UIFigure, 'Dictionary file not found!', 'File Error');
            return;
        end
            
        % Read dictionary file
        fileID = fopen(dictionaryFile, 'r');
        rawDictionary = textscan(fileID, '%s');
        fclose(fileID);
            
        % Store the dictionary words in a cell array
        app.dictionary = rawDictionary{1};


        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
    
%== START NEW GAME, USING SELECTED GAME MODE TO DECIDE TILE SET

% Initialize tile bag as an empty cell array
app.tileBag = {};

% Define letter counts for each game mode
selectedSize = app.ModeDD.Value;

switch selectedSize
    case 'Default' 
        % 144 tiles, same as Bananagrams set
        letter_count = struct('E',18, 'A',13, 'I',12, 'O',11, 'T',9, 'R',9, ...
            'N',8, 'D',6, 'S',6, 'U',6, 'L',5, 'G',4, 'B',3, 'C',3, 'F',3, ...
            'H',3, 'M',3, 'P',3, 'V',3, 'W',3, 'Y',3, 'J',2, 'K',2, 'Q',2, ...
            'X',2, 'Z',2);

    case 'Mini'
        % 78 tiles
        letter_count = struct('E',9, 'A',7, 'I',6, 'O',5, 'T',5, 'R',5, ...
            'N',4, 'D',3, 'S',3, 'U',3, 'L',3, 'G',2, 'B',2, 'C',2, 'F',2, ...
            'H',2, 'M',2, 'P',2, 'V',2, 'W',2, 'Y',2, 'J',1, 'K',1, 'Q',1, ...
            'X',1, 'Z',1);

    case 'Wild' % FIX THIS FIX THIS
        % 144 randomized tiles 
        % updated 8/2/24
        letter_count = generate_wild_bag();

    case 'X-Small'
        % 8 tiles, for testing purposes
        letter_count = struct('E',2, 'A',2, 'T',1, 'R',1, 'D',1, 'S',1);

    otherwise
        error('Unknown tile bag size selected');
end


% Prepare letters from selected game mode
letters = fieldnames(letter_count);

for i = 1:numel(letters)
    letter = letters{i};
    count = letter_count.(letter);
    app.tileBag = [app.tileBag, repmat({letter}, 1, count)];
end


% Display the number of remaining tiles
app.RemainingTilesEditField.Value = num2str(numel(app.tileBag));

% Initialize the pool and hoard (will also reset after prev
% game)
app.pool = {};
app.hoard = {};
delete(app.PoolPanel.Children);


        end

        % Button pushed function: NewTileButton
        function NewTileButtonPushed(app, event)
    %updated 8/2/24. Still needs to clear once new game starts. Need to
    %better space out letters

    %== EACH PUSH ADDS A RANDOM LETTER FROM THE TILE BAG TO THE POOL

    % if any letters are left, add a tile
    if ~isempty(app.tileBag) && numel(app.pool)<16
        % Randomly select letter from tile bag
        idx = randi(numel(app.tileBag));
        newTile = app.tileBag{idx};

        % Add random tile to pool
        app.pool{end+1} = newTile;
       
        % Remove selected tile from tile bag
        app.tileBag(idx) = [];

        % Display updated number of remaining tiles
        app.RemainingTilesEditField.Value = num2str(numel(app.tileBag));
        

        % Display new tile in pool panel, with space from previous
        % Define the number of items per column
        itemsPerColumn = 4;
        columnWidth = 30;
        rowHeight = 18;


        % Calculate the position for the new label
        column = floor((numel(app.pool) - 1) / itemsPerColumn);
        row = mod((numel(app.pool) - 1), itemsPerColumn);

        xPos = 30 + column * columnWidth;
        yPos = 150 - row * rowHeight;

        uilabel(app.PoolPanel, 'Text', newTile, 'Position', [xPos, yPos, columnWidth, rowHeight]);
        

    elseif isempty(app.tileBag)
        % If empty but button pressed, display a message if there are no tiles left in the tile bag
        uialert(app.UIFigure, 'NO MORE TILES REMAINING', 'Tile Bag Empty');
    
        % ** error hopefully not necessary, as empty bag should ->
        % end of game page

    elseif numel(app.pool)>=16
        % error if user spams new tiles without making new words
        uialert(app.UIFigure, 'MUST PLAY NEW WORD', 'Temporary Limit Reached');
    
    end

end


%=== FUNCTION TO CHECK IF A WORD IS VALID
% 1) must be in dictionary and 2) must be minimum length selected by uwer
function isValid = isValidWord(app, word)
    isValid = any(strcmpi(app.dictionary, word)) && length(word) >= app.LengthSpinner.Value;
        
    % once established as valid here, letters will be compared to pool,
    % hoard, etc.

        end

        % Value changed function: NewWordEditField
        function NewWordEditFieldValueChanged(app, event)
            % NOT CERTAIN ABOUT THIS FIX??

            word = app.NewWordEditField.Value;
            if ~isempty(word)
                app.submitWord(word);
            end
        end

        % Callback function: not associated with a component
        function PressEnter(app, event)
            % 'ENTER' KEY TRIGGERS WORD SUBMISSION
            
            if strcmp(event.Key, 'return') % Check if Enter key is pressed
                NewWord = app.NewWordEditField.Value;
                submitWord(app, NewWord);
            end

                    
        end

        % Callback function: not associated with a component
        function PressSpace(app, event)
            % 'SPACE' KEY TRIGGERS NEW TILE (just like button push)

            if strcmp(event.Key, 'space') % check if Space key is pressed
                NewTileButtonPushed(app, []);
            end
        end

        % Size changed function: UIFigure
        function WindowSizeChanged(app, event)
            % establish desire width, height, ratio
            figWidth = app.UIFigure.Position(3);
            figHeight = app.UIFigure.Position(4);
            
            aspectRatio = 644 / 438; % Desired aspect ratio
            
            % lock aspect ratio if size changed
            if figWidth / figHeight > aspectRatio
                newWidth = figHeight * aspectRatio;
                app.UIFigure.Position(3) = newWidth;
            else
                newHeight = figWidth / aspectRatio;
                app.UIFigure.Position(4) = newHeight;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @WindowSizeChanged, true);
            app.Container = uipanel(app.UIFigure);
            app.PoolPanel = uipanel(app.Container);
            app.PoolLabel = uilabel(app.PoolPanel);
            app.PoolLabel.Text = 'POOL';
            app.NewGameButton = uibutton(app.Container, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'NEW GAME';
            app.HoardPanel = uipanel(app.Container);
            app.HoardLabel = uilabel(app.HoardPanel);
            app.HoardLabel.Text = 'HOARD';
            app.ValidWordLamp = uilamp(app.HoardPanel);
            app.NewWordEditField = uieditfield(app.HoardPanel, 'text');
            app.NewWordEditField.ValueChangedFcn = createCallbackFcn(app, @NewWordEditFieldValueChanged, true);
            app.NewTileButton = uibutton(app.HoardPanel, 'push');
            app.NewTileButton.ButtonPushedFcn = createCallbackFcn(app, @NewTileButtonPushed, true);
            app.NewTileButton.Text = 'NEW TILE';
            app.NewWordDD = uidropdown(app.HoardPanel);
            app.NewWordDD.Items = {'NEW WORD', 'STEAL'};
            app.NewWordDD.Placeholder = 'NEW WORD';
            app.NewWordDD.Value = 'NEW WORD';
            app.RemainingTilesEditFieldLabel = uilabel(app.HoardPanel);
            app.RemainingTilesEditFieldLabel.Text = 'Remaining Tiles';
            app.RemainingTilesEditField = uieditfield(app.HoardPanel, 'text');
            app.SettingsPanel = uipanel(app.Container);
            app.SettingsLabel = uilabel(app.SettingsPanel);
            app.SettingsLabel.Text = 'SETTINGS';
            app.ModeDD = uidropdown(app.SettingsPanel);
            app.ModeDD.Items = {'Default', 'Mini', 'Wild', 'X-Small'};
            app.ModeDD.Value = 'Default';
            app.ModeLabel = uilabel(app.SettingsPanel);
            app.ModeLabel.Text = 'GAME MODE';
            app.LengthLabel = uilabel(app.SettingsPanel);
            app.LengthLabel.Text = 'MINIMUM LENGTH';
            app.LengthSpinner = uispinner(app.SettingsPanel);
            app.LengthSpinner.Limits = [3 7];
            app.LengthSpinner.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNATCH_IT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end